classdef SpringDampeningPIDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GoButton                        matlab.ui.control.Button
        SetpointmEditField              matlab.ui.control.NumericEditField
        SetpointmEditFieldLabel         matlab.ui.control.Label
        DerivitiveCoefficientEditField  matlab.ui.control.NumericEditField
        DerivitiveCoefficientEditFieldLabel  matlab.ui.control.Label
        IntegralCoefficientEditField    matlab.ui.control.NumericEditField
        IntegralCoefficientEditFieldLabel  matlab.ui.control.Label
        ProportionalCoefficientEditField  matlab.ui.control.NumericEditField
        ProportionalCoefficientEditFieldLabel  matlab.ui.control.Label
        SpringconstantkNmEditField      matlab.ui.control.NumericEditField
        SpringconstantkNmLabel          matlab.ui.control.Label
        DampeningCoefficientNsmEditField  matlab.ui.control.NumericEditField
        DampeningCoefficientNsmEditFieldLabel  matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    PreviousTimeBefore = []; % Time data for the second-most recent run
    PreviousPositionBefore = []; % Position data for the second-most recent run
    PreviousTime = []; % Time data for the most recent run
    PreviousPosition = []; % Position data for the most recent run
    CurrentTime = []; % Time data for the current run
    CurrentPosition = []; % Position data for the current run
end


    methods (Access = private) %Add professors function as a method to call
        
        function [time, x] = simulatePIDControl(app, m, c, k, kP, kI, kD, setpoint)
            % Time vector
            dt = 0.01;
            time = 0:dt:10;
        
            % Pre-allocate output
            x = zeros(size(time)); % Position
            v = zeros(size(time)); % Velocity
            lastError = 0;
            integral = 0;
        
            for i = 2:length(time)
                % Error calculation
                error = setpoint - x(i-1);
        
                % Proportional term
                P = kP * error;
        
                % Integral term
                integral = integral + error * dt;
                I = kI * integral;
        
                % Derivative term
                derivative = (error - lastError) / dt;
                D = kD * derivative;
        
                % Control signal (force applied)
                adjustment = P + I + D;
        
                % System dynamics (Euler integration)
                a = (adjustment - c * v(i-1) - k * x(i-1)) / m; % Acceleration
                v(i) = v(i-1) + a * dt; % Velocity update
                x(i) = x(i-1) + v(i) * dt; % Position update
        
                % Update previous error
                lastError = error;         
                end
        end
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function Run(app, event)

            try
                % Retrieve user inputs
                m = app.MasskgEditField.Value; % Mass (kg)
                c = app.DampeningCoefficientNsmEditField.Value; % Damping coefficient (N·s/m)
                k = app.SpringconstantkNmEditField.Value; % Spring constant (N/m)
                kP = app.ProportionalCoefficientEditField.Value; % Proportional gain
                kI = app.IntegralCoefficientEditField.Value; % Integral gain
                kD = app.DerivitiveCoefficientEditField.Value; % Derivative gain
                setpoint = app.SetpointmEditField.Value; % Desired setpoint (position)
        
                % Validate inputs
                if any([m, c, k, kP, kI, kD] <= 0) || isnan(setpoint)
                    errordlg('Please enter valid positive values for all inputs.');
                    return;
                end
        
                % Run the simulation
                [time, position] = simulatePIDControl(app, m, c, k, kP, kI, kD, setpoint);
        
                % Clear the axes before plotting
                cla(app.UIAxes);
        
                % Plot the second-most recent run (if it exists) as a dashed line
                if ~isempty(app.PreviousTimeBefore) && ~isempty(app.PreviousPositionBefore)
                    plot(app.UIAxes, app.PreviousTimeBefore, app.PreviousPositionBefore, '--', 'DisplayName', 'Run Before Last');
                    hold(app.UIAxes, 'on');
                end
        
                % Plot the most recent previous run (if it exists) as a dotted line
                if ~isempty(app.PreviousTime) && ~isempty(app.PreviousPosition)
                    plot(app.UIAxes, app.PreviousTime, app.PreviousPosition, ':', 'DisplayName', 'Last Run');
                    hold(app.UIAxes, 'on');
                end
        
                % Plot the current run as a solid line
                plot(app.UIAxes, time, position, '-', 'DisplayName', 'Current Run');
                hold(app.UIAxes, 'off');
        
                % Update the legend
                legend(app.UIAxes, 'show');
        
                % Shift the data to store the last two runs:
                % Move "previous" data to "before previous"
                app.PreviousTimeBefore = app.PreviousTime;
                app.PreviousPositionBefore = app.PreviousPosition;
        
                % Move "current" data to "previous"
                app.PreviousTime = app.CurrentTime;
                app.PreviousPosition = app.CurrentPosition;
        
                % Store the new run as the "current"
                app.CurrentTime = time;
                app.CurrentPosition = position;
        
            catch ME
                % Handle errors
                errordlg(['An error occurred: ' ME.message]);
            end

                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampeningCoefficientNsmEditFieldLabel = uilabel(app.UIFigure);
            app.DampeningCoefficientNsmEditFieldLabel.Text = 'Dampening Coefficient (N*s/m)';
            app.DampeningCoefficientNsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringconstantkNmLabel = uilabel(app.UIFigure);
            app.SpringconstantkNmLabel.Text = 'Spring constant k (N/m)';
            app.SpringconstantkNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProportionalCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalCoefficientEditFieldLabel.Text = 'Proportional Coefficient';
            app.ProportionalCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegralCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralCoefficientEditFieldLabel.Text = 'Integral Coefficient';
            app.IntegralCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivitiveCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.DerivitiveCoefficientEditFieldLabel.Text = 'Derivitive Coefficient';
            app.DerivitiveCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointmEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointmEditFieldLabel.Text = 'Setpoint (m)';
            app.SetpointmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpringDampeningPIDApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end