classdef lineFollowingPIDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GOButton                        matlab.ui.control.Button
        RandomSeedEditField             matlab.ui.control.NumericEditField
        RandomSeedEditFieldLabel        matlab.ui.control.Label
        SetpointEditField               matlab.ui.control.NumericEditField
        SetpointEditFieldLabel          matlab.ui.control.Label
        DerivativeGainkDEditField       matlab.ui.control.NumericEditField
        DerivativeGainkDEditFieldLabel  matlab.ui.control.Label
        IntegralGainkIEditField         matlab.ui.control.NumericEditField
        IntegralGainkIEditFieldLabel    matlab.ui.control.Label
        ProportionalGainkPEditField     matlab.ui.control.NumericEditField
        ProportionalGainkPEditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    % Previous Run Data
    PreviousDeviation = []; % Deviation data for the previous run
    PreviousLeftMotor = []; % Left motor speeds for the previous run
    PreviousRightMotor = []; % Right motor speeds for the previous run
    PreviousError = []; % Error data for the previous run

    % Current Run Data (stored during simulation)
    CurrentDeviation = []; % Deviation data for the current run
    CurrentLeftMotor = []; % Left motor speeds for the current run
    CurrentRightMotor = []; % Right motor speeds for the current run
    CurrentError = []; % Error data for the current run
end
 
    methods (Access = private)
        
        function [time, sensorReading, leftMotorSpeed, rightMotorSpeed, position] = simulateLineFollowerPololu(kP, kI, kD, setpoint, randomSeed)
    % Time Vector
    dt = 0.1;
    time = 0:dt:10;

    % Set the random seed
    if nargin > 4 && ~isempty(randomSeed)
        rng(randomSeed);
    else
        % Shuffle the generator
        rng('shuffle');
    end

    % PID variables
    integral = 0;
    lastError = 0;

    % Robot parameters
    setSpeed = 150;
    maxSpeed = 255;
    minSpeed = 0;

    % Initial values
    sensorReading = zeros(size(time));
    sensorReading(1) = setpoint;

    % Motor speed arrays
    leftMotorSpeed = zeros(size(time));
    rightMotorSpeed = zeros(size(time));
    leftMotorSpeed(1) = setSpeed;
    rightMotorSpeed(1) = setSpeed;

    % Simulate line following
    for i = 2:length(time)
        % Small deviations to simulate a dynamic line following, drift, etc
        sensorReading(i) = sensorReading(i-1) + 25 * (rand - 0.5);

        % Large deviations to simulate turns, etc
        if mod(i, 50) == 0 % Every 5 seconds, introduce a bigger deviation
            sensorReading(i) = setpoint + 200 * (rand - 0.5); % Bigger random deviation
        end

        % Limit the reading to acceptable values (range is 0 to 7000)
        sensorReading(i) = max(min(sensorReading(i), 7000), 0);

        % Calculate adjustment
        error = setpoint - sensorReading(i);
        integral = integral + error*dt;
        derivative = (error - lastError)/dt;
        adjustment = kP * error + kI * integral + kD * derivative;

        % Adjust motor speeds
        leftMotorSpeed(i) = setSpeed + adjustment;
        rightMotorSpeed(i) = setSpeed - adjustment;

        % Limit motor speeds to usable range
        leftMotorSpeed(i) = max(min(leftMotorSpeed(i), maxSpeed), minSpeed);
        rightMotorSpeed(i) = max(min(rightMotorSpeed(i), maxSpeed), minSpeed);

        % Update previous error
        lastError = error;
    end

    position = cumsum((leftMotorSpeed - rightMotorSpeed));
end
            
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GoButtonPushed(app, event)


    try
        % Retrieve user inputs
        kP = app.ProportionalGainkPEditField.Value;
        kI = app.IntegralGainkIEditField.Value;
        kD = app.DerivativeGainkDEditField.Value;
        setpoint = app.SetpointEditField.Value;
        randomSeed = app.RandomSeedEditField.Value;

        % Validate inputs
        if any([kP, kI, kD, setpoint] <= 0) || isnan(randomSeed)
            errordlg('Please enter valid positive values for all inputs.');
            return;
        end

        % Run the simulation
        [time, deviation, leftMotor, rightMotor, error] = simulateLineFollowerPololu(kP, kI, kD, setpoint, randomSeed);

        % Clear axes
        cla(app.UIAxes); % Deviation
        cla(app.UIAxes2); % Motor speeds
        cla(app.UIAxes3); % Position/Error

        % Plot previous run (if it exists)
        if ~isempty(app.PreviousDeviation)
            plot(app.UIAxes, time, app.PreviousDeviation, '--', 'DisplayName', 'Previous Deviation');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes2, time, app.PreviousLeftMotor, '--r', 'DisplayName', 'Previous Left Motor');
            plot(app.UIAxes2, time, app.PreviousRightMotor, '--b', 'DisplayName', 'Previous Right Motor');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes3, time, app.PreviousError, '--', 'DisplayName', 'Previous Error');
            hold(app.UIAxes3, 'on');
        end

        % Plot current run
        plot(app.UIAxes, time, deviation, '-', 'DisplayName', 'Current Deviation');
        hold(app.UIAxes, 'off');
        plot(app.UIAxes2, time, leftMotor, '-r', 'DisplayName', 'Current Left Motor');
        plot(app.UIAxes2, time, rightMotor, '-b', 'DisplayName', 'Current Right Motor');
        hold(app.UIAxes2, 'off');
        plot(app.UIAxes3, time, error, '-', 'DisplayName', 'Current Error');
        hold(app.UIAxes3, 'off');

        % Update legends
        legend(app.UIAxes, 'show');
        legend(app.UIAxes2, 'show');
        legend(app.UIAxes3, 'show');

        % Store current run data for future plotting
        app.PreviousDeviation = deviation;
        app.PreviousLeftMotor = leftMotor;
        app.PreviousRightMotor = rightMotor;
        app.PreviousError = error;

    catch ME
        errordlg(['An error occurred: ' ME.message]);
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Deviation')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Deviation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Motor Speeds')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Speed (0-255)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Position')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Position')
            zlabel(app.UIAxes3, 'Z')
            app.ProportionalGainkPEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalGainkPEditFieldLabel.Text = 'Proportional Gain (kP)';
            app.ProportionalGainkPEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegralGainkIEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralGainkIEditFieldLabel.Text = 'Integral Gain (kI)';
            app.IntegralGainkIEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivativeGainkDEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeGainkDEditFieldLabel.Text = 'Derivative Gain (kD)';
            app.DerivativeGainkDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditField.Value = 3500;
            app.RandomSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RandomSeedEditFieldLabel.Text = 'Random Seed';
            app.RandomSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GOButton.Text = 'GO!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lineFollowingPIDApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end