classdef simulataTemperatureControlPIDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResetSimulationButton        matlab.ui.control.Button
        RunSimulationButton          matlab.ui.control.Button
        DisturbanceFactorEditField   matlab.ui.control.NumericEditField
        DisturbanceFactorEditFieldLabel  matlab.ui.control.Label
        InitialTemperatureEditField  matlab.ui.control.NumericEditField
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        SetpointEditField            matlab.ui.control.NumericEditField
        SetpointEditFieldLabel       matlab.ui.control.Label
        kDEditField                  matlab.ui.control.NumericEditField
        kDEditFieldLabel             matlab.ui.control.Label
        kIEditField                  matlab.ui.control.NumericEditField
        kIEditFieldLabel             matlab.ui.control.Label
        kPEditField                  matlab.ui.control.NumericEditField
        kPEditFieldLabel             matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PreviousTemperature = []; % Temperature data from the previous run
        PreviousError = [];       % Error data from the previous run
    end

    
    methods (Access = private)
        
        % This is my temperature PID control function. This has everything the
        % PID example functions have but also includes disturbance, which represents
        % the temperature loss or gain due to external factors that my PID has
        % to help against.
        function [time, temperature, error] = simulateTemparaturePIDControl(~, kP, kI, kD, setpoint, initialTemp, disturbance)
            % Time variables
            dt = 0.1;
            time = 0:dt:100; % Time vector from 0 to 100 in steps of 0.1 [1x1000 row]

            % Create a temperature array and error array
            temperature = zeros(size(time));
            error = zeros(size(time));

            % Create and store initial data
            temperature(1) = initialTemp;
            integral = 0;
            lastError = setpoint - initialTemp;

            for i = 2:length(time)
                % Define the PID variables
                error(i) = setpoint - temperature(i-1);
                P = kP * error(i);
                integral = integral + error(i) * dt;
                I = kI * integral;
                derivative = (error(i) - lastError) / dt;
                D = kD * derivative;

                % Use PID variables to determine temperature adjustments
                heatingPower = P + I + D;
                temperature(i) = temperature(i-1) + (heatingPower * dt) + (disturbance * dt);

                %debugging thingy
                disp(['Time: ', num2str(time(i)), ', Temp: ', num2str(temperature(i)), ', Error: ', num2str(error(i))]);

                % Update previous error to the current error before restarting the loop
                lastError = error(i);
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function runSimulation(app, event)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            try
                % Save user inputs
                kP = app.kPEditField.Value;
                kI = app.kIEditField.Value;
                kD = app.kDEditField.Value;
                setpoint = app.SetpointEditField.Value;
                initialTemp = app.InitialTemperatureEditField.Value;
                disturbance = app.DisturbanceFactorEditField.Value;

                % Make sure inputs arent negative but allow zero this time
                if any([kP, kI, kD, setpoint, initialTemp, disturbance] < 0)
                    errordlg('All input values must be non-negative.');
                    return;
                end

                % Run the simulation
                [time, temperature, error] = app.simulateTemparaturePIDControl(kP, kI, kD, setpoint, initialTemp, disturbance);

                % Clear previous plots
                cla(app.UIAxes);
                cla(app.UIAxes2);

                % Plot previous run 
                if ~isempty(app.PreviousTemperature)
                    plot(app.UIAxes, time, app.PreviousTemperature, '--', 'DisplayName', 'Previous Run');
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes2, time, app.PreviousError, '--', 'DisplayName', 'Previous Run');
                    hold(app.UIAxes2, 'on');
                end

                % Plot everything
                plot(app.UIAxes, time, temperature, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Current Run');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Temperature (°C)');
                title(app.UIAxes, 'Temperature vs. Time');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');

                plot(app.UIAxes2, time, error, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Current Run');
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Error (°C)');
                title(app.UIAxes2, 'Error vs. Time');
                legend(app.UIAxes2, 'show');
                grid(app.UIAxes2, 'on');
                hold(app.UIAxes2, 'off');

                % Store current run data
                app.PreviousTemperature = temperature;
                app.PreviousError = error;

            catch ME
                errordlg(['An error occurred: ' ME.message]);
            end
        end

        % Button pushed function: ResetSimulationButton
        function ResetSimulationbuttonPushed(app, event)
        
            % Clear plots and reset all inputs to zero
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.kPEditField.Value = 0;
            app.kIEditField.Value = 0;
            app.kDEditField.Value = 0;
            app.SetpointEditField.Value = 0;
            app.InitialTemperatureEditField.Value = 0;
            app.DisturbanceFactorEditField.Value = 0;
            app.PreviousTemperature = [];
            app.PreviousError = [];
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature vs. Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Error vs. Time')
            xlabel(app.UIAxes2, 'TIme')
            ylabel(app.UIAxes2, 'Error')
            zlabel(app.UIAxes2, 'Z')
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisturbanceFactorEditFieldLabel = uilabel(app.UIFigure);
            app.DisturbanceFactorEditFieldLabel.Text = 'Disturbance Factor';
            app.DisturbanceFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetSimulationButton = uibutton(app.UIFigure, 'push');
            app.ResetSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulationbuttonPushed, true);
            app.ResetSimulationButton.Text = 'Reset Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulataTemperatureControlPIDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end