classdef FourierSeriesVisualisor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotFourierSeriesButton         matlab.ui.control.Button
        FourierSeriesExpressionLabel    matlab.ui.control.Label
        XEndEditField                   matlab.ui.control.NumericEditField
        XEndEditFieldLabel              matlab.ui.control.Label
        XStartEditField                 matlab.ui.control.NumericEditField
        XStartEditFieldLabel            matlab.ui.control.Label
        FouierTermsEditField            matlab.ui.control.NumericEditField
        FouierTermsEditFieldLabel       matlab.ui.control.Label
        PeriodicFunctionEditField       matlab.ui.control.EditField
        PeriodicFunctionEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotFourierSeriesButton
        function PlotButtonPushed(app, event)

    try
        % Get user inputs from the respective UI components
        funcInput = app.PeriodicFunctionEditField.Value; % Periodic function input
        terms = app.FouierTermsEditField.Value; % Number of terms
        xStart = app.XStartEditField.Value; % Range start
        xEnd = app.XEndEditField.Value; % Range end
        
        % Validate user inputs
        if isnan(terms) || terms <= 0 || isnan(xStart) || isnan(xEnd) || xStart >= xEnd
            errordlg('Please provide valid inputs for all fields.');
            return;
        end
        
        % Symbolic processing
        syms x;
        periodicFunc = str2sym(funcInput); % Convert input to symbolic expression
        
        % Compute Fourier series coefficients
        T = xEnd - xStart; % Period
        a0 = (1/T) * int(periodicFunc, x, xStart, xEnd);
        an = @(n) (2/T) * int(periodicFunc * cos(2*pi*n*x/T), x, xStart, xEnd);
        bn = @(n) (2/T) * int(periodicFunc * sin(2*pi*n*x/T), x, xStart, xEnd);
        
        % Build the Fourier series
        fourierSeries = a0 / 2;
        for n = 1:terms
            fourierSeries = fourierSeries + an(n)*cos(2*pi*n*x/T) + bn(n)*sin(2*pi*n*x/T);
        end
        
        % Convert symbolic expressions to function handles
        originalFunc = matlabFunction(periodicFunc);
        approxFunc = matlabFunction(fourierSeries);
        
        % Plotting on the UIAxes
        fplot(app.UIAxes, originalFunc, [xStart, xEnd], 'DisplayName', 'Original Function', 'LineWidth', 1.5);
        hold(app.UIAxes, 'on');
        fplot(app.UIAxes, approxFunc, [xStart, xEnd], 'DisplayName', sprintf('Fourier Series (%d terms)', terms), 'LineWidth', 1.5);
        hold(app.UIAxes, 'off');
        
        % Configure the plot
        legend(app.UIAxes, 'show');
        title(app.UIAxes, 'Fourier Series Approximation');
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'f(x)');
        grid(app.UIAxes, 'on');
        
    catch ME
        % Display an error dialog if something goes wrong
        errordlg(['An error occurred: ' ME.message]);
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'UIAxes';
            app.PeriodicFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodicFunctionEditFieldLabel.Tag = 'PeriodicFunctionEditField';
            app.PeriodicFunctionEditFieldLabel.Text = 'Periodic Function';
            app.PeriodicFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FouierTermsEditFieldLabel = uilabel(app.UIFigure);
            app.FouierTermsEditFieldLabel.Tag = 'FourierTermsEditField';
            app.FouierTermsEditFieldLabel.Text = 'Fouier Terms';
            app.FouierTermsEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStartEditFieldLabel = uilabel(app.UIFigure);
            app.XStartEditFieldLabel.Text = 'X-Start';
            app.XStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStartEditField.Tag = 'XStartEditField';
            app.XEndEditFieldLabel = uilabel(app.UIFigure);
            app.XEndEditFieldLabel.Text = 'X-End';
            app.XEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEndEditField.Tag = 'XEndEditField';
            app.FourierSeriesExpressionLabel = uilabel(app.UIFigure);
            app.FourierSeriesExpressionLabel.Tag = 'FourierSeriesLabel';
            app.FourierSeriesExpressionLabel.Text = 'Fourier Series Expression';
            app.PlotFourierSeriesButton = uibutton(app.UIFigure, 'push');
            app.PlotFourierSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotFourierSeriesButton.Tag = 'PlotButton';
            app.PlotFourierSeriesButton.Text = 'Plot Fourier Series';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesVisualisor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end