classdef FunctionPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotButton                   matlab.ui.control.Button
        XEndEditField                matlab.ui.control.NumericEditField
        XEndEditFieldLabel           matlab.ui.control.Label
        XStartEditField              matlab.ui.control.NumericEditField
        XStartEditFieldLabel         matlab.ui.control.Label
        SymbolicExpressionEditField  matlab.ui.control.EditField
        SymbolicExpressionEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function ButtonPushed(app, event)
            try
                % Get user input for the symbolic expression
                inputExpr = strtrim(app.SymbolicExpressionEditField.Value); % Trim spaces
                if isempty(inputExpr)
                    errordlg('Symbolic expression field is empty. Please enter a valid expression.', 'Error');
                    return;
                end
        
                % Convert input to a symbolic expression
                expr = str2sym(inputExpr);

                % Get the x-axis range
                xStart = app.XStartEditField.Value;
                xEnd = app.XEndEditField.Value;
        
                % Validate the range
                if xStart >= xEnd
                    errordlg('Invalid range. Start value must be less than end value.', 'Error');
                    return;
                end
        
                % Plot the function
                fplot(app.UIAxes, expr, [xStart, xEnd]);
        
                % Add labels and grid
                title(app.UIAxes, 'Function Plot');
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                grid(app.UIAxes, 'on');
            catch ME
                % Handle invalid symbolic expressions
                disp(ME.message); % Debugging output
                errordlg('Invalid symbolic expression. Please check your input.', 'Error');
            end
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'PlotAxis';
            app.SymbolicExpressionEditFieldLabel = uilabel(app.UIFigure);
            app.SymbolicExpressionEditFieldLabel.Text = 'Symbolic Expression';
            app.SymbolicExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.SymbolicExpressionEditField.Tag = 'SymbolicExpressionEditField';
            app.XStartEditFieldLabel = uilabel(app.UIFigure);
            app.XStartEditFieldLabel.Text = 'X-Start';
            app.XStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStartEditField.Tag = 'XStartField';
            app.XEndEditFieldLabel = uilabel(app.UIFigure);
            app.XEndEditFieldLabel.Text = 'X-End';
            app.XEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEndEditField.Tag = 'XEndField';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PlotButton.Tag = 'PlotButton';
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end