classdef SymbolicDiffIntApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        XEndEditField                matlab.ui.control.NumericEditField
        XEndEditFieldLabel           matlab.ui.control.Label
        XStartEditField              matlab.ui.control.NumericEditField
        XStartEditFieldLabel         matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        ResultLabel                  matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        WithRespectToDropDown        matlab.ui.control.DropDown
        WithRespectToDropDownLabel   matlab.ui.control.Label
        OpperationButtonGroup        matlab.ui.container.ButtonGroup
        DifferentiateButton          matlab.ui.control.RadioButton
        IntegrateButton              matlab.ui.control.RadioButton
        SymbolicExpressionEditField  matlab.ui.control.EditField
        SymbolicExpressionEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
   
            try
                % Get user inputs and validate them
                inputExpr = strtrim(app.SymbolicExpressionEditField.Value); % Trim spaces
                if isempty(inputExpr)
                    errordlg('Symbolic expression field is empty. Please enter a valid expression.', 'Error');
                    return;
                end
        
                expr = str2sym(inputExpr); % Convert to symbolic expression
                variable = app.WithRespectToDropDown.Value; % Get selected variable
                if isempty(variable)
                    errordlg('Please select a variable from the dropdown.', 'Error');
                    return;
                end
        
                % Determine operation based on radio button selection
                if app.DifferentiateButton.Value
                    result = diff(expr, variable); % Perform differentiation
                elseif app.IntegrateButton.Value
                    result = int(expr, variable); % Perform integration
                else
                    errordlg('Please select an operation.', 'Error');
                    return;
                end
        
                % Display result
                app.ResultLabel.Text = sprintf('Result: %s', char(result));
            catch ME
                disp(ME.message); % Debugging
                errordlg('Invalid symbolic expression. Please check your input.', 'Error');
            end
        

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
     
            try
                % Get user inputs and validate them
                inputExpr = strtrim(app.SymbolicExpressionEditField.Value); % Trim spaces
                if isempty(inputExpr)
                    errordlg('Symbolic expression field is empty. Please enter a valid expression.', 'Error');
                    return;
                end
        
                expr = str2sym(inputExpr); % Convert to symbolic expression
                variable = app.WithRespectToDropDown.Value;
                if isempty(variable)
                    errordlg('Please select a variable from the dropdown.', 'Error');
                    return;
                end
        
                % Get range inputs
                xStart = app.XStartEditField.Value;
                xEnd = app.XEndEditField.Value;
                if xStart >= xEnd
                    errordlg('Start value must be less than end value.', 'Error');
                    return;
                end
        
                % Determine operation
                if app.DifferentiateButton.Value
                    derivedExpr = diff(expr, variable); % Differentiate
                elseif app.IntegrateButton.Value
                    derivedExpr = int(expr, variable); % Integrate
                else
                    errordlg('Please select an operation to plot.', 'Error');
                    return;
                end
        
                % Convert symbolic expressions to function handles
                fOriginal = matlabFunction(expr, 'Vars', sym(variable));
                fDerived = matlabFunction(derivedExpr, 'Vars', sym(variable));
        
                % Plot on the provided axes
                hold(app.UIAxes, 'off'); % Clear existing plots
                fplot(app.UIAxes, fOriginal, [xStart, xEnd], 'DisplayName', 'Original');
                hold(app.UIAxes, 'on');
                fplot(app.UIAxes, fDerived, [xStart, xEnd], 'DisplayName', 'Derived/Integrated');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
            catch ME
                disp(ME.message); % Debugging
                errordlg('Error plotting the functions. Check your inputs.', 'Error');
            end
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot Axis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'ResultAxis';
            app.SymbolicExpressionEditFieldLabel = uilabel(app.UIFigure);
            app.SymbolicExpressionEditFieldLabel.Text = 'Symbolic Expression';
            app.SymbolicExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.SymbolicExpressionEditField.Tag = 'ExpressionField';
            app.OpperationButtonGroup = uibuttongroup(app.UIFigure);
            app.OpperationButtonGroup.Title = 'Opperation';
            app.IntegrateButton = uiradiobutton(app.OpperationButtonGroup);
            app.IntegrateButton.Tag = 'IntegrateRadio';
            app.IntegrateButton.Text = 'Integrate';
            app.IntegrateButton.Value = true;
            app.DifferentiateButton = uiradiobutton(app.OpperationButtonGroup);
            app.DifferentiateButton.Tag = 'DifferentiateRadio';
            app.DifferentiateButton.Text = 'Differentiate';
            app.WithRespectToDropDownLabel = uilabel(app.UIFigure);
            app.WithRespectToDropDownLabel.Text = 'With Respect To';
            app.WithRespectToDropDown = uidropdown(app.UIFigure);
            app.WithRespectToDropDown.Items = {'x', 'y', 'z'};
            app.WithRespectToDropDown.Tag = 'VariableDropdown';
            app.WithRespectToDropDown.Value = 'x';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tag = 'CalculateButton';
            app.CalculateButton.Text = 'Calculate';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Tag = 'ResultLabel';
            app.ResultLabel.Text = 'Result';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'PlotButton';
            app.PlotButton.Text = 'Plot';
            app.XStartEditFieldLabel = uilabel(app.UIFigure);
            app.XStartEditFieldLabel.Text = 'X-Start';
            app.XStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStartEditField.Tag = 'XStartEditField';
            app.XEndEditFieldLabel = uilabel(app.UIFigure);
            app.XEndEditFieldLabel.Text = 'X-End';
            app.XEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEndEditField.Tag = 'XEndEditField';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolicDiffIntApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end